classdef Robot_arm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        PortMenu                      matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        LogTextAreaLabel              matlab.ui.control.Label
        LogTextArea                   matlab.ui.control.TextArea
        SendButton                    matlab.ui.control.Button
        DurationEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        AngleEditFieldLabel           matlab.ui.control.Label
        AngleEditField                matlab.ui.control.NumericEditField
        SerialPortTextAreaLabel       matlab.ui.control.Label
        SerialPortTextArea            matlab.ui.control.TextArea
        ReadSerialButton              matlab.ui.control.Button
        AngleEditField_2Label         matlab.ui.control.Label
        AngleEditField_2              matlab.ui.control.NumericEditField
        DurationEditField_2Label      matlab.ui.control.Label
        DurationEditField_2           matlab.ui.control.NumericEditField
        AccelerationEditFieldLabel    matlab.ui.control.Label
        AccelerationEditField         matlab.ui.control.NumericEditField
        ResetButton                   matlab.ui.control.Button
        SliderLabel                   matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        AccelerationEditField_2Label  matlab.ui.control.Label
        AccelerationEditField_2       matlab.ui.control.NumericEditField
        SendButton_2                  matlab.ui.control.Button
        ResetButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        serial_obj;
        target = 0;
        stepper0_angle = 0; 
        stepper1_angle = 0; 
        current_2 = 0;
        iTextBox = 0;
    end
    methods (Access = private)
        
        
        function DisplayTextArea(app,message,isError)
            if isError == 1
                app.iTextBox = app.iTextBox + 1;
                
                str_cell = sprintf("%.3d:Error : %s",app.iTextBox,message);
                app.LogTextArea.Value(app.iTextBox) = cellstr(str_cell);
            else
                app.iTextBox = app.iTextBox + 1;
                
                str_cell = sprintf("%.3d:Succes: %s",app.iTextBox,message);
                app.LogTextArea.Value(app.iTextBox) = cellstr(str_cell);
            end
        end
        
        
        function [status,new_duration] = StepperMotorCommand(app,motor_select,relative_angle,absolute_angle,duration,acceleration)
            %start command with the motor selection
            str = "M" + motor_select;
            %Compute maximum velocity
            velocity = 0.5*(acceleration*duration -sqrt((acceleration*duration)^2-4*acceleration * abs(relative_angle)));
            
            %get sign of the angle 
            if absolute_angle < 0
                sign = '-';
            else
                sign = '+';
            end
                
            if velocity >= 1 && velocity <= 99 && isreal(velocity)
                %Motor command
                str =  str ...                                              %Motor select
                    + num2str(duration,'%02.f') ...                       %duration of rotation
                    + num2str(acceleration, '%02.f') ...                  %acceleration of stepper motor
                    + sign ...                                            %sign of the angle
                    + num2str(abs(absolute_angle), '%03.f')                       %absolute value of the angle
                write(app.serial_obj,str,'char');
                disp_str = "velocity is " + velocity;
                DisplayTextArea(app,disp_str,0);
                status = 1;
                new_duration = duration;
            else
                if(velocity < 1)
                    disp_str = "velocity is to small", "velocity is " + velocity;
                elseif(velocity > 99)
                    disp_str = "velocity is to big", "velocity is " + velocity;
                else
                    disp_str = "NaN";
                end
                %display Error message
                DisplayTextArea(app,disp_str,1);
                status = 0;
                new_duration = round(sqrt(4*acceleration*abs(relative_angle))/acceleration + 1);
            end
            
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ports = serialportlist;
            for c = 1: length(ports)
                m =  uimenu(app.PortMenu,'Text',ports(c));
            end
            app.serial_obj = serialport("COM4",9600);
            
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            acceleration = app.AccelerationEditField.Value;
            duration = app.DurationEditField.Value;
            relative_angle = app.AngleEditField.Value - app.stepper0_angle;
            absolute_angle = app.AngleEditField.Value;
            if(relative_angle == 0)
                DisplayTextArea(app,'Motor is already here',1);
            else 
                [status, new_duration] = StepperMotorCommand(app,1,relative_angle,absolute_angle,duration,acceleration)
                if status == 1
                    app.stepper0_angle = app.AngleEditField.Value;
                else 
                    app.DurationEditField.Value = new_duration;
                end
            end 
        end

        % Menu selected function: PortMenu
        function PortMenuSelected(app, event)
           app.PortMenu.Children(1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ReadSerialButton
        function ReadSerialButtonPushed(app, event)
            if app.serial_obj.NumBytesAvailable > 0
                data = read(app.serial_obj,app.serial_obj.NumBytesAvailable, 'char');
                app.SerialPortTextArea.Value = data;
            end
        end

        % Callback function
        function Button_2Pushed(app, event)
      
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.stepper0_angle = 0;
            app.AngleEditField.Value = 0;
            app.DurationEditField.Value = 0;
            app.AccelerationEditField.Value = 0;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            angle = app.Slider.Value;
            sign = "+";
             if(angle <0 )
                sign = "-";
            end
            str = "M0" +"00"+"00" + sign + num2str(abs(angle), '%03.f')
            write(app.serial_obj,str,'char');
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.stepper1_angle = 0;
            app.AngleEditField_2.Value = 0;
            app.DurationEditField_2.Value = 0;
            app.AccelerationEditField_2.Value = 0;
        end

        % Button pushed function: SendButton_2
        function SendButton_2Pushed(app, event)
            acceleration = app.AccelerationEditField_2.Value;
            duration = app.DurationEditField_2.Value;
            relative_angle = app.AngleEditField_2.Value - app.stepper1_angle;
            absolute_angle = app.AngleEditField_2.Value;
            if(relative_angle == 0)
                DisplayTextArea(app,'Motor is already here',1);
            else 
                [status, new_duration] = StepperMotorCommand(app,2,relative_angle,absolute_angle,duration,acceleration)
                if status == 1
                    app.stepper1_angle = app.AngleEditField_2.Value;
                else 
                    app.DurationEditField_2.Value = new_duration;
                end
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.PortMenu = uimenu(app.File);
            app.PortMenu.MenuSelectedFcn = createCallbackFcn(app, @PortMenuSelected, true);
            app.PortMenu.Text = 'Port';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.SerialPortTextAreaLabel = uilabel(app.UIFigure);
            app.SerialPortTextAreaLabel.Text = 'Serial Port';
            app.SerialPortTextArea = uitextarea(app.UIFigure);
            app.ReadSerialButton = uibutton(app.UIFigure, 'push');
            app.ReadSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSerialButtonPushed, true);
            app.ReadSerialButton.Text = 'Read Serial';
            app.AngleEditField_2Label = uilabel(app.UIFigure);
            app.AngleEditField_2Label.Text = 'Angle';
            app.AngleEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField_2Label = uilabel(app.UIFigure);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.AccelerationEditFieldLabel.Text = 'Acceleration';
            app.AccelerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AccelerationEditField_2Label = uilabel(app.UIFigure);
            app.AccelerationEditField_2Label.Text = 'Acceleration';
            app.AccelerationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SendButton_2 = uibutton(app.UIFigure, 'push');
            app.SendButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendButton_2Pushed, true);
            app.SendButton_2.Text = 'Send';
            app.ResetButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_arm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end